classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        TypeofPolarizationDropDownLabel  matlab.ui.control.Label
        TypeofPolarizationDropDown    matlab.ui.control.DropDown
        E10VmEditFieldLabel           matlab.ui.control.Label
        E10VmEditField                matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField          matlab.ui.control.NumericEditField
        er1EditFieldLabel             matlab.ui.control.Label
        er1EditField                  matlab.ui.control.NumericEditField
        ur1EditFieldLabel             matlab.ui.control.Label
        ur1EditField                  matlab.ui.control.NumericEditField
        theta_idegressEditFieldLabel  matlab.ui.control.Label
        theta_idegressEditField       matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function perpendicular(app)
            er1 = app.er1EditField.Value;
            ur1 = app.ur1EditField.Value;
            E10 = app.E10VmEditField.Value;
            f = app.FrequencyHzEditField.Value;
            e0 = 8.87e-12;
            u0 = 1.67e-7;
            beta1 = (2*pi.*f)*sqrt(e0*er1*u0*ur1);
            n1 = sqrt((u0*ur1)/(e0*er1));
            theta_i = app.theta_idegressEditField.Value*pi/180;
            x = 0:0.01:1;
            z = 0:0.01:1;
            
            Eiy = zeros(101, 101);                    
            Ery = zeros(101, 101);
            
            Hix = zeros(101, 101);
            Hiz = zeros(101, 101);
            
            Hrx = zeros(101, 101);
            Hrz = zeros(101, 101);
            for n=1:1:101
                for m=1:1:101
                   Eiy(n, m) = E10.*exp(-1i*(beta1.*sin(theta_i).*x(n) + ...
                       beta1.*cos(theta_i).*z(m)));
                   Ery(n, m) = -E10.*exp(-1i*(beta1.*sin(theta_i).*x(n) - ...
                       beta1.*cos(theta_i).*z(m)));
                   Hix(n, m) = -cos(theta_i).*(E10/n1).*exp(-1i*(beta1.*sin(theta_i).*x(n) + ...
                       beta1.*cos(theta_i).*z(m)));
                   Hiz(n, m) = sin(theta_i).*(E10/n1).*exp(-1i*(beta1.*sin(theta_i).*x(n) + ...
                       beta1.*cos(theta_i).*z(m)));
                   Hrx(n, m) = -cos(theta_i).*(E10/n1).*exp(-1i*(beta1.*sin(theta_i).*x(n) - ...
                       beta1.*cos(theta_i).*z(m)));
                   Hrz(n, m) = -sin(theta_i).*(E10/n1).*exp(-1i*(beta1.*sin(theta_i).*x(n) - ...
                       beta1.*cos(theta_i).*z(m)));
                end
            end
            surf(app.UIAxes, x, z, real(Ery+Eiy));
            surf(app.UIAxes2, x, z, real(Hix+Hiz+Hrz+Hrx));            
        end
        
        function parallel(app)
            er1 = app.er1EditField.Value;
            ur1 = app.ur1EditField.Value;
            E10 = app.E10VmEditField.Value;
            f = app.FrequencyHzEditField.Value;
            e0 = 8.87e-12;
            u0 = 1.67e-7;
            beta1 = (2*pi.*f)*sqrt(e0*er1*u0*ur1);
            n1 = sqrt((u0*ur1)/(e0*er1));
            theta_i = app.theta_idegressEditField.Value*pi/180;
            x = 0:0.01:1;
            z = 0:0.01:1;
            Eix = zeros(101, 101);
            Eiz = zeros(101, 101);
            
            Erx = zeros(101, 101);
            Erz = zeros(101, 101);
            
            Hiy = zeros(101, 101);
            Hry = zeros(101, 101);
            
            for n=1:1:101
                for m=1:1:101
                   Eix(n, m) = cos(theta_i).*E10.*exp(-1i*(beta1.*sin(theta_i).*x(n) + ...
                       beta1.*cos(theta_i).*z(m)));
                   Eiz(n, m) = -sin(theta_i).*E10.*exp(-1i*(beta1.*sin(theta_i).*x(n) + ...
                       beta1.*cos(theta_i).*z(m)));
                   Hiy(n, m) = (E10/n1).*exp(-1i*(beta1.*sin(theta_i).*x(n) + ...
                       beta1.*cos(theta_i).*z(m)));
                   Erx(n, m) = -cos(theta_i).*E10.*exp(-1i*(beta1.*sin(theta_i).*x(n) - ...
                       beta1.*cos(theta_i).*z(m)));
                   Erz(n, m) = -sin(theta_i).*E10.*exp(-1i*(beta1.*sin(theta_i).*x(n) - ...
                       beta1.*cos(theta_i).*z(m)));
                   Hry(n, m) = (E10/n1).*exp(-1i*(beta1.*sin(theta_i).*x(n) - ...
                       beta1.*cos(theta_i).*z(m)));
                end
            end
            surf(app.UIAxes, x, z, real(Eix+Erx+Eiz+Erz));
            surf(app.UIAxes2, x, z, real(Hiy+Hry));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.TypeofPolarizationDropDown.Value;
            if(value == "Perpendicular Polarization")
                perpendicular(app);
            else
                parallel(app);
            end
        end

        % Value changed function: TypeofPolarizationDropDown
        function TypeofPolarizationDropDownValueChanged(app, event)
            value = app.TypeofPolarizationDropDown.Value;
            if(value == "Perpendicular Polarization")
                perpendicular(app);
            else
                parallel(app);
            end
        end

        % Value changed function: E10VmEditField
        function E10VmEditFieldValueChanged(app, event)
            value = app.E10VmEditField.Value;
            if(value<0)
                app.E10VmEditField.Value = -1*app.E10VmEditField.Value;
            end
            if(app.TypeofPolarizationDropDown.Value == "Perpendicular Polarization")
                perpendicular(app);
            else
                parallel(app);
            end
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            if(value<0)
                app.FrequencyHzEditField.Value = -1*app.FrequencyHzEditField.Value;
            end
            if(app.TypeofPolarizationDropDown.Value == "Perpendicular Polarization")
                perpendicular(app);
            else
                parallel(app);
            end
        end

        % Value changed function: er1EditField
        function er1EditFieldValueChanged(app, event)
            value = app.er1EditField.Value;
            if(value<1)
                app.er1EditField.Value = 1;
            end
            if(app.TypeofPolarizationDropDown.Value == "Perpendicular Polarization")
                perpendicular(app);
            else
                parallel(app);
            end
        end

        % Value changed function: ur1EditField
        function ur1EditFieldValueChanged(app, event)
            value = app.ur1EditField.Value;
            if(value<1)
                app.ur1EditField.Value = 1;
            end
            if(app.TypeofPolarizationDropDown.Value == "Perpendicular Polarization")
                perpendicular(app);
            else
                parallel(app);
            end
        end

        % Value changed function: theta_idegressEditField
        function theta_idegressEditFieldValueChanged(app, event)
            value = app.theta_idegressEditField.Value;
            if(value<0)
                app.theta_idegressEditField.Value = -1*app.theta_idegressEditField.Value;
            end
            if(value>90)
                app.theta_idegressEditField.Value = 90;
            end
            if(app.TypeofPolarizationDropDown.Value == "Perpendicular Polarization")
                perpendicular(app);
            else
                parallel(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Electric Field')
            xlabel(app.UIAxes, 'Z')
            ylabel(app.UIAxes, 'X')
            app.UIAxes.ZTickLabel = {'0'; '0.5.1'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Magnetic Field')
            xlabel(app.UIAxes2, 'Z')
            ylabel(app.UIAxes2, 'X')
            app.TypeofPolarizationDropDownLabel = uilabel(app.UIFigure);
            app.TypeofPolarizationDropDownLabel.Text = 'Type of Polarization';
            app.TypeofPolarizationDropDown = uidropdown(app.UIFigure);
            app.TypeofPolarizationDropDown.Items = {'Perpendicular Polarization', 'Parallel Polarization'};
            app.TypeofPolarizationDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofPolarizationDropDownValueChanged, true);
            app.TypeofPolarizationDropDown.Value = 'Perpendicular Polarization';
            app.E10VmEditFieldLabel = uilabel(app.UIFigure);
            app.E10VmEditFieldLabel.Text = 'E10 (V/m)';
            app.E10VmEditField = uieditfield(app.UIFigure, 'numeric');
            app.E10VmEditField.ValueChangedFcn = createCallbackFcn(app, @E10VmEditFieldValueChanged, true);
            app.E10VmEditField.Value = 1;
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 1000000000;
            app.er1EditFieldLabel = uilabel(app.UIFigure);
            app.er1EditFieldLabel.Text = 'er1';
            app.er1EditField = uieditfield(app.UIFigure, 'numeric');
            app.er1EditField.ValueChangedFcn = createCallbackFcn(app, @er1EditFieldValueChanged, true);
            app.er1EditField.Value = 4;
            app.ur1EditFieldLabel = uilabel(app.UIFigure);
            app.ur1EditFieldLabel.Text = 'ur1';
            app.ur1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ur1EditField.ValueChangedFcn = createCallbackFcn(app, @ur1EditFieldValueChanged, true);
            app.ur1EditField.Value = 4;
            app.theta_idegressEditFieldLabel = uilabel(app.UIFigure);
            app.theta_idegressEditFieldLabel.Text = 'theta_i (degress)';
            app.theta_idegressEditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_idegressEditField.ValueChangedFcn = createCallbackFcn(app, @theta_idegressEditFieldValueChanged, true);
            app.theta_idegressEditField.Value = 60;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end